classdef stim_rec_app_nwb_v7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SamplingrateEditFieldLabel      matlab.ui.control.Label
        SamplingrateEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        DutycycleEditFieldLabel         matlab.ui.control.Label
        DutycycleEditField              matlab.ui.control.NumericEditField
        ONtimeEditFieldLabel            matlab.ui.control.Label
        ONtimeEditField                 matlab.ui.control.NumericEditField
        OFFtimeEditFieldLabel           matlab.ui.control.Label
        OFFtimeEditField                matlab.ui.control.NumericEditField
        NooftrialsEditFieldLabel        matlab.ui.control.Label
        NooftrialsEditField             matlab.ui.control.NumericEditField
        FrequencychirpCheckBox          matlab.ui.control.CheckBox
        SquarewaveCheckBox              matlab.ui.control.CheckBox
        STARTButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SessiondescriptionEditFieldLabel  matlab.ui.control.Label
        SessiondescriptionEditField     matlab.ui.control.EditField
        SessionidentifierEditFieldLabel  matlab.ui.control.Label
        SessionidentifierEditField      matlab.ui.control.EditField
        MothIDEditFieldLabel            matlab.ui.control.Label
        MothIDEditField                 matlab.ui.control.EditField
        MothageEditFieldLabel           matlab.ui.control.Label
        MothageEditField                matlab.ui.control.EditField
        SexEditFieldLabel               matlab.ui.control.Label
        SexEditField                    matlab.ui.control.EditField
        BridgebalanceEditFieldLabel     matlab.ui.control.Label
        BridgebalanceEditField          matlab.ui.control.NumericEditField
        SineCheckBox                    matlab.ui.control.CheckBox
        AmplitudesweepCheckBox          matlab.ui.control.CheckBox
        WhitenoiseCheckBox              matlab.ui.control.CheckBox
        Amp_minEditFieldLabel           matlab.ui.control.Label
        Amp_minEditField                matlab.ui.control.NumericEditField
        Amp_maxEditFieldLabel           matlab.ui.control.Label
        Amp_maxEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        BridgebalanceButton             matlab.ui.control.Button
        SqrwavefreqEditFieldLabel       matlab.ui.control.Label
        SqrwavefreqEditField            matlab.ui.control.EditField
        SinewavefreqEditFieldLabel      matlab.ui.control.Label
        SinewavefreqEditField           matlab.ui.control.EditField
        Sweep_freqEditFieldLabel        matlab.ui.control.Label
        Sweep_freqEditField             matlab.ui.control.NumericEditField
        DecreasingFrequencychirpCheckBox  matlab.ui.control.CheckBox
        StepCheckBox                    matlab.ui.control.CheckBox
        StepdurationsEditFieldLabel     matlab.ui.control.Label
        StepdurationsEditField          matlab.ui.control.EditField
        ImpulseCheckBox                 matlab.ui.control.CheckBox
        ImpulsedurationEditFieldLabel   matlab.ui.control.Label
        ImpulsedurationEditField        matlab.ui.control.EditField
        DyeInjectionButton              matlab.ui.control.Button
        SumofsinesCheckBox              matlab.ui.control.CheckBox
        SumsinefreqEditFieldLabel       matlab.ui.control.Label
        SumsinefreqEditField            matlab.ui.control.EditField
        Whitenoise2CheckBox             matlab.ui.control.CheckBox
        SinewithnoiseCheckBox           matlab.ui.control.CheckBox
        PowerofsinenoiseEditFieldLabel  matlab.ui.control.Label
        PowerofsinenoiseEditField       matlab.ui.control.EditField
        VariableampstepsCheckBox        matlab.ui.control.CheckBox
        StepamplitudesEditFieldLabel    matlab.ui.control.Label
        StepamplitudesEditField         matlab.ui.control.EditField
        MaxfrequencyEditFieldLabel      matlab.ui.control.Label
        MaxfrequencyEditField           matlab.ui.control.NumericEditField
        NostimulusrecordingCheckBox     matlab.ui.control.CheckBox
        StairsCheckBox                  matlab.ui.control.CheckBox
        RampCheckBox                    matlab.ui.control.CheckBox
        RampdurationsEditFieldLabel     matlab.ui.control.Label
        RampdurationsEditField          matlab.ui.control.EditField
        OnlystimulusButton              matlab.ui.control.Button
        StepdurationEditFieldLabel      matlab.ui.control.Label
        StepdurationEditField           matlab.ui.control.NumericEditField
        RampdurationEditFieldLabel      matlab.ui.control.Label
        RampdurationEditField           matlab.ui.control.NumericEditField
        RampstepdurationEditFieldLabel  matlab.ui.control.Label
        RampstepdurationEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        sampling_rate = 1e5; % Description
        amplitude;
        frequency;
        dutycycle;
        on_time;
        off_time;
        no_of_trials;
        %total_duration = on_time + off_time;
        chirp_stim;
        dec_chirp_stim;
        sqr_stim;
        step_stim;
        sine_stim;
        inc_dec_stair;
        impulse_stim;
        impulse_dur;
        ramp_stim;
        sum_sine_stim;
        amp_min;
        amp_max;
        amp_sweep_stim;
        gwn_stim;
        gwn_stim_2;
        output_data_rand_row;
        output_stim_array = [];
        output_stim_array_rand;
        noisy_sine_stim;
        step_var_amp_stim;
        
        
        chirp;
        sqr;
        step;
        output_data =[];
        output_data_rand = [];
        
        sqr_freq = [1 0.5 0.25 0.125 0.0625 2 5 10 30 60];
        step_dur = [1 2 5 0.5 0.25 0.125 0.0625];
        ramp_dur = [0.5 1 2 5];
        sqr_duty = [ 50 10 30 60 80];
        sine_freq = [1 0.5 0.25 0.125 2 5 10 30 60 90 120];
        sum_sine_freqs = [10, 25, 30, 35, 50];
        sine_noise = [-30, -40, -50];
        step_amp = [0.01,0.015, 0.02,0.025, 0.03, 0.035];
        max_chirp_freq= 150;
        amp_sweep_freq=5;
        gwn_power=-20;
        stair_step_dur = 4;
        stair_ramp_dur = 1;
        ramp_step_dur = 2;
        
        
        s;
        session_description;
        session_identifier;
        moth_ID;
        moth_age;
        sex;
        bias_current;
        bridge_balance;
        filename;
        stim_description_string_array;
        stim_description;
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            app.sampling_rate = app.SamplingrateEditField.Value;
            
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.amplitude = app.AmplitudeEditField.Value;
            
        end

        % Value changed function: DutycycleEditField
        function DutycycleEditFieldValueChanged(app, event)
            app.dutycycle = app.DutycycleEditField.Value;
            
        end

        % Value changed function: ONtimeEditField
        function ONtimeEditFieldValueChanged(app, event)
            app.on_time  = app.ONtimeEditField.Value;
            
        end

        % Value changed function: OFFtimeEditField
        function OFFtimeEditFieldValueChanged(app, event)
            app.off_time = app.OFFtimeEditField.Value;
            
        end

        % Value changed function: NooftrialsEditField
        function NooftrialsEditFieldValueChanged(app, event)
            app.no_of_trials = app.NooftrialsEditField.Value;
            
        end

        % Value changed function: FrequencychirpCheckBox
        function FrequencychirpCheckBoxValueChanged(app, event)
            value = app.FrequencychirpCheckBox.Value;
            
            
            if value == 1
                %app.chirp = 1;
                app.chirp_stim = generate_chirp_stim();
                
                %                 figure();
                %                 plot(app.chirp_stim);
                
                app.output_data = [app.output_data; app.chirp_stim];
                
            end
            
            
            function freq_chirp = generate_chirp_stim()
                
                chirp_dur = app.on_time + 2*app.off_time;
                flank_off_time = zeros(1, app.off_time*app.sampling_rate);
                t1= 0:1/app.sampling_rate:app.on_time;
                %disp(size(t1));
                freq_chirp = [flank_off_time app.amplitude*chirp(t1,0,app.on_time,app.max_chirp_freq, 'linear', -90) flank_off_time];
                %disp(size(freq_chirp));
                freq_chirp = repmat(freq_chirp, app.no_of_trials, 1);
                stim_name = "frq_chirp";
                stim_array = repmat(stim_name, app.no_of_trials, 1);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(size(app.output_stim_array));
                
            end
        end

        % Value changed function: SquarewaveCheckBox
        function SquarewaveCheckBoxValueChanged(app, event)
            value = app.SquarewaveCheckBox.Value;
            
            if value == 1
                %                 app.sqr = 1;
                app.sqr_stim = generate_sqr_wave();
                %figure();
                % plot(app.sqr_stim);
                
                app.output_data = [app.output_data; app.sqr_stim];
            end
            
            function sqr_all_trials = generate_sqr_wave()
                stim_dur_sqr = 2*app.off_time+app.on_time;
                t = app.off_time:1/app.sampling_rate:stim_dur_sqr-app.off_time;
                
                %                 sqr_freq_rand = app.sqr_freq(randperm(length(app.sqr_freq)));
                %                 sqr_freq_rand_str = repmat(num2str(sqr_freq_rand'), app.no_of_trials,1);
                
                sqr_freq_str = repmat(num2str(app.sqr_freq'), app.no_of_trials,1);
                
                stim_name = "sqr_";
                stim_array = [repmat(stim_name, app.no_of_trials*length(app.sqr_freq), 1) sqr_freq_str] ;
                stim_array = join(stim_array);
                app.output_stim_array = [app.output_stim_array; stim_array ];
                disp(app.output_stim_array)
                
                sqr_wave = -square(2*pi*app.sqr_freq'*t, 50);
                sqr_wave = app.amplitude.*sqr_wave;
                off_time_array = zeros(length(app.sqr_freq), app.off_time*app.sampling_rate);
                sqr_wave_flanked = [off_time_array sqr_wave off_time_array];
                %plot(sqr_wave);
                
                sqr_all_trials = [];
                for i=1:length(app.sqr_freq)
                    one_stim_trials = repmat(sqr_wave_flanked(i,:), app.no_of_trials, 1);
                    sqr_all_trials = [sqr_all_trials; one_stim_trials];
                end
                
            end
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %             devices = daq.getDevices;
            app.s = daq.createSession('ni');
            addAnalogInputChannel(app.s,'Dev2', 0, 'Voltage'); %For recording neuron
            addAnalogInputChannel(app.s,'Dev2', 1, 'Voltage'); %Force output from indenter
            addAnalogInputChannel(app.s,'Dev2', 2, 'Voltage'); %Hall effect sensor output
            addAnalogOutputChannel(app.s,'Dev2', 0, 'Voltage'); %Waveform generator
            
            
            app.s.Rate = app.sampling_rate;


            %randomise the stimuli and trials
            idx = randperm(size(app.output_data,1));
            app.output_data_rand = app.output_data(idx, :);
            app.output_data_rand_row = reshape(app.output_data_rand', [], 1);
            
            app.output_stim_array_rand = app.output_stim_array(idx,:);
            app.stim_description_string_array = (app.output_stim_array_rand)';
            
            app.stim_description = char(join(app.stim_description_string_array, ","));
            
            %             figure();
            %             plot(app.output_data_rand_row);
            
            queueOutputData(app.s,app.output_data_rand_row);
            [rec_data,time,~] = app.s.startForeground;
            
            %             dataset = [];
            %             dataset = [time, app.output_data_rand_row, rec_data];
            
            cd 'F:\Chinmayee\Recordings'
            
            formatOut = 'yyyy.mm.dd';
            date = datestr(now,formatOut);
            if 7~=exist(date, 'dir')
                [~, msg] = mkdir(date);
                disp(msg);
            end
            
            
            dir_path = strcat(pwd,'\', date, '\');
            cd(dir_path);


            %NWB file
            session_start_time = datetime; %date has to be update from the app input
            
            nwb = NwbFile( ...
                'session_description', app.session_description, ...
                'identifier', app.session_identifier, ...
                'session_start_time', session_start_time);
            
            %disp(nwb);
            
            %Subject - animal
            nwb.general_subject = types.core.Subject( ...
                'description', app.moth_ID, 'age',app.moth_age, ...
                'sex', app.sex, 'species', 'Daphnis nerii');
            
            nwb.general_stimulus = num2str([app.sampling_rate; app.amplitude; app.dutycycle; app.on_time; app.off_time; app.no_of_trials]);
            %Recording meta-data
            device_name = 'NI USB-6363';
            ic_elec_name = 'Sharp electrode';
            
            nwb.general_devices.set(device_name, types.core.Device());
            device_link = types.untyped.SoftLink(['/general/devices/' device_name]);
            
            ic_elec = types.core.IntracellularElectrode( ...
                'device', device_link, ...
                'description', 'my description');
            
            nwb.general_intracellular_ephys.set(ic_elec_name, ic_elec);
            ic_elec_link = types.untyped.SoftLink(['/general/intracellular_ephys/' ic_elec_name]);
            
            %stimulus
            
            %             description = 'Stimulus to JO';
            stimulus_name = 'mechanical_stimulus';         %change this for air puff


            nwb.stimulus_presentation.set(stimulus_name, ...
                types.core.CurrentClampStimulusSeries( ...
                'electrode', ic_elec_link, ...
                'gain', 10, ...
                'stimulus_description', app.stim_description, ...
                'data_unit', 'V', ...
                'data', app.output_data_rand', ...
                'timestamps', time));
            
            %Response
            
            response_name = 'response_to_JO_stimulation';
            
            nwb.acquisition.set(response_name, ...
                types.core.IZeroClampSeries( ...
                'bias_current', 200, ... % Unit: pA
                'bridge_balance', app.bridge_balance, ... % Unit: Ohm
                'capacitance_compensation', [], ... % Unit: Farad
                'timestamps', time, ... % seconds
                'data', rec_data, ...
                'data_unit', 'V', ...
                'electrode', ic_elec_link, ...
                'stimulus_description', 'Mechanical stimulus to JO')); %update GUI to collect these data
            
            f = sprintf("%s.nwb", app.filename);
            
            % Check if file exists and update
            if isfile(f)
                f = sprintf("%s_new.nwb", app.filename);
                warning('Did you forget to change the filename again? There! I saved you once! Change it now');
            end
            
            nwbExport(nwb, f);


            stop(app.s);
            beep;
            
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            disp('Program ended')
            stop(app.s);
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            app.filename = app.FilenameEditField.Value;
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.output_data=[];
            app.output_data_rand = [];
            app.output_stim_array = [];
            app.stim_description_string_array = [];
            app.stim_description = [];
            
        end

        % Value changed function: SessiondescriptionEditField
        function SessiondescriptionEditFieldValueChanged(app, event)
            app.session_description = app.SessiondescriptionEditField.Value;
            
        end

        % Value changed function: SessionidentifierEditField
        function SessionidentifierEditFieldValueChanged(app, event)
            app.session_identifier = app.SessionidentifierEditField.Value;
            
        end

        % Value changed function: MothIDEditField
        function MothIDEditFieldValueChanged(app, event)
            app.moth_ID = app.MothIDEditField.Value;
            
        end

        % Value changed function: MothageEditField
        function MothageEditFieldValueChanged(app, event)
            app.moth_age = app.MothageEditField.Value;
            
        end

        % Value changed function: SexEditField
        function SexEditFieldValueChanged(app, event)
            app.sex = app.SexEditField.Value;
            
        end

        % Value changed function: BridgebalanceEditField
        function BridgebalanceEditFieldValueChanged(app, event)
            app.bridge_balance = app.BridgebalanceEditField.Value;
            
        end

        % Value changed function: SineCheckBox
        function SineCheckBoxValueChanged(app, event)
            value = app.SineCheckBox.Value;
            
            if value == 1
                %app.sine = 1;
                app.sine_stim = generate_sine_wave();
                %figure();
                %plot(app.sine_stim);
                
                app.output_data = [app.output_data; app.sine_stim];
            end
            
            function sine_all_trials = generate_sine_wave()
                stim_dur_sine = 2*app.off_time+app.on_time;
                t = app.off_time:1/app.sampling_rate:stim_dur_sine-app.off_time;
                
                sine_freq_rand = app.sine_freq(randperm(length(app.sine_freq)));
                
                sine_freq_rand_str = repmat(num2str(sine_freq_rand'), app.no_of_trials,1);
                % disp(size(sine_freq_rand_str))
                stim_name = "sin_";
                stim_array = [repmat(stim_name, app.no_of_trials*length(sine_freq_rand), 1) sine_freq_rand_str] ;
                stim_array = join(stim_array);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(app.output_stim_array)
                
                sine_wave = sin(2*pi*sine_freq_rand'*t);
                sine_wave = app.amplitude.*sine_wave;
                off_time_array = zeros(length(app.sine_freq), app.off_time*app.sampling_rate);
                sine_wave_flanked = [off_time_array sine_wave off_time_array];
                %plot(sine_wave_flanked);
                
                sine_all_trials = [];
                for i=1:length(app.sine_freq)
                    one_stim_trials = repmat(sine_wave_flanked(i,:), app.no_of_trials, 1);
                    sine_all_trials = [sine_all_trials; one_stim_trials];
                end
                
            end
        end

        % Value changed function: AmplitudesweepCheckBox
        function AmplitudesweepCheckBoxValueChanged(app, event)
            value = app.AmplitudesweepCheckBox.Value;
            
            if value == 1
                %app.sine = 1;
                app.amp_sweep_stim = generate_amp_sweep();
                %                 figure();
                %                 plot(app.amp_sweep_stim);
                
                app.output_data = [app.output_data; app.amp_sweep_stim];
            end
            
            function amp_sweep_all_trials = generate_amp_sweep()
                stim_dur_amp_sweep = 2*app.off_time+app.on_time;
                t1 = app.off_time:1/app.sampling_rate:stim_dur_amp_sweep-app.off_time;
                
                amp_sweep = linspace(app.amp_min, app.amp_max, length(t1));
                
                freq = app.amp_sweep_freq;
                sine_amp_sweep = amp_sweep.*sin(2*pi*freq*t1);
                
                off_time_array = zeros(1,app.off_time*app.sampling_rate);
                sine_amp_sweep_flanked = [off_time_array sine_amp_sweep off_time_array];
                %plot(sine_amp_sweep_flanked);
                
                amp_sweep_all_trials = repmat(sine_amp_sweep_flanked, app.no_of_trials, 1);
                stim_name = sprintf("amp_sweep_%d", app.amp_sweep_freq);
                stim_array = repmat(stim_name, app.no_of_trials, 1);
                app.output_stim_array = [app.output_stim_array; stim_array];
                
                
            end
        end

        % Value changed function: Amp_minEditField
        function Amp_minEditFieldValueChanged(app, event)
            value = app.Amp_minEditField.Value;
            app.amp_min = value;
        end

        % Value changed function: Amp_maxEditField
        function Amp_maxEditFieldValueChanged(app, event)
            value = app.Amp_maxEditField.Value;
            app.amp_max = value;
        end

        % Value changed function: PowerEditField
        function PowerEditFieldValueChanged(app, event)
            value = app.PowerEditField.Value;
            app.gwn_power = value;
        end

        % Value changed function: WhitenoiseCheckBox
        function WhitenoiseCheckBoxValueChanged(app, event)
            value = app.WhitenoiseCheckBox.Value;
            
            if value == 1
                %app.chirp = 1;
                app.gwn_stim = generate_gwn_stim();
                
                %figure();
                %plot(app.gwn_stim);
                
                app.output_data = [app.output_data; app.gwn_stim];
            end
            
            
            function gwn_stim_trials = generate_gwn_stim()
                
                gwn_stim_dur = app.on_time + 2*app.off_time;
                flank_off_time = zeros(1, app.off_time*app.sampling_rate);
                t1= app.off_time:1/app.sampling_rate:gwn_stim_dur-app.off_time;
                disp(size(t1));
                
                gwn = wgn(1,length(t1), app.gwn_power, 1, 1); % impedence of load = 1, randseed = 1
                
                d = designfilt('lowpassiir','FilterOrder',10, ...
                    'HalfPowerFrequency',300, ...
                    'SampleRate',app.sampling_rate);
                blgwn = filtfilt(d, gwn);
                
                
                gwn_stim_trials = [flank_off_time blgwn flank_off_time];
                gwn_stim_trials = repmat(gwn_stim_trials, app.no_of_trials, 1);
                
                stim_name = "blwgn";
                stim_array = repmat(stim_name, app.no_of_trials, 1);
                app.output_stim_array = [app.output_stim_array; stim_array];
                
            end
            
            
        end

        % Button pushed function: BridgebalanceButton
        function BridgebalanceButtonPushed(app, event)
            devices = daq.getDevices;
            app.s = daq.createSession('ni');
            %             addAnalogInputChannel(app.s,'Dev1', 0, 'Voltage');
            addAnalogOutputChannel(app.s,'Dev2', 1, 'Voltage');
            app.s.Rate = 10000;
            
            amp = 0.01;
            
            t=0:1/app.s.Rate:60;
            freq = 1;
            square_wave = square(2*pi*freq*t, 50);
            bridge_balance_wave = amp*square_wave;
            queueOutputData(app.s,bridge_balance_wave');
            
            %             figure();
            %             plot(step);
            %             title('Output Data Queued');
            
            [~,~,~] = app.s.startForeground;
            
            stop(app.s);
            
        end

        % Value changed function: SqrwavefreqEditField
        function SqrwavefreqEditFieldValueChanged(app, event)
            value = app.SqrwavefreqEditField.Value;
            app.sqr_freq = str2num(string(value));
        end

        % Value changed function: SinewavefreqEditField
        function SinewavefreqEditFieldValueChanged(app, event)
            value = app.SinewavefreqEditField.Value;
            app.sine_freq = str2num(string(value));
        end

        % Value changed function: Sweep_freqEditField
        function Sweep_freqEditFieldValueChanged(app, event)
            value = app.Sweep_freqEditField.Value;
            app.amp_sweep_freq = value;
        end

        % Value changed function: DecreasingFrequencychirpCheckBox
        function DecreasingFrequencychirpCheckBoxValueChanged(app, event)
            value = app.DecreasingFrequencychirpCheckBox.Value;
            
            if value == 1
                %app.chirp = 1;
                app.dec_chirp_stim = generate_dec_chirp_stim();
                
                %                 figure();
                %                 plot(app.chirp_stim);
                
                app.output_data = [app.output_data; app.dec_chirp_stim];
                
            end
            
            
            function freq_chirp = generate_dec_chirp_stim()
                
                chirp_dur = app.on_time + 2*app.off_time;
                flank_off_time = zeros(1, app.off_time*app.sampling_rate);
                t1= 0:1/app.sampling_rate:app.on_time;
                %disp(size(t1));
                freq_chirp = flip([flank_off_time app.amplitude*chirp(t1,0,app.on_time,app.max_chirp_freq, 'linear', -90) flank_off_time]);
                %disp(size(freq_chirp));
                freq_chirp = repmat(freq_chirp, app.no_of_trials, 1);
                stim_name = "dec_frq_chirp";
                stim_array = repmat(stim_name, app.no_of_trials, 1);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(size(app.output_stim_array));
                
            end
        end

        % Value changed function: StepCheckBox
        function StepCheckBoxValueChanged(app, event)
            value = app.StepCheckBox.Value;
            
            if value == 1
                app.step = 1;
                app.step_stim = generate_step_stim();
                figure();
                plot(app.step_stim);
                
                app.output_data = [app.output_data; app.step_stim];
                
                
            end
            
            function step_all_trials = generate_step_stim()
                
                off_step = app.sampling_rate*app.off_time;
                step_dur_rand = app.step_dur(randperm(length(app.step_dur)));
                disp(step_dur_rand)
                on_step = step_dur_rand * app.sampling_rate;
                total_dur = 2*off_step + on_step;
                
                
                step_wave = zeros(length(app.step_dur),max(total_dur));
                step_all_trials = [];
                
                for i=1:length(app.step_dur)
                    step_wave(i, off_step:off_step+on_step(i)) = app.amplitude;
                    %                       one_stim_trials = repmat(step_wave(i,:), 1, app.no_of_trials);
                    %                       step_all_trials = [step_all_trials one_stim_trials];
                    
                end
                
                one_stim_trials = repmat(step_wave, app.no_of_trials, 1);
                step_all_trials = [step_all_trials; one_stim_trials];
                
                step_dur_rand_str = repmat(num2str(step_dur_rand'), app.no_of_trials,1);
                % disp(size(sine_freq_rand_str))
                stim_name = "step_";
                stim_array = [repmat(stim_name, app.no_of_trials*length(step_dur_rand), 1) step_dur_rand_str] ;
                stim_array = join(stim_array);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(app.output_stim_array)
            end


        end

        % Value changed function: StepdurationsEditField
        function StepdurationsEditFieldValueChanged(app, event)
            value = app.StepdurationsEditField.Value;
            app.step_dur = str2num(string(value));
            disp(app.step_dur);
        end

        % Value changed function: ImpulseCheckBox
        function ImpulseCheckBoxValueChanged(app, event)
            value = app.ImpulseCheckBox.Value;
            
            if value ==1
                
                imp_on = app.amplitude*ones(1,app.impulse_dur*app.sampling_rate); % 3 ms
                imp_off = zeros(1, app.off_time*app.sampling_rate);
                impulse_single_trial = [imp_off imp_on imp_off];
                app.impulse_stim = repmat(impulse_single_trial, app.no_of_trials, 1);
                
                app.output_data = [app.output_data; app.impulse_stim];
                
                stim_name = "impulse";
                stim_array = repmat(stim_name, app.no_of_trials, 1);
                app.output_stim_array = [app.output_stim_array; stim_array];
                
            end
        end

        % Value changed function: ImpulsedurationEditField
        function ImpulsedurationEditFieldValueChanged(app, event)
            value = app.ImpulsedurationEditField.Value;
            app.impulse_dur = str2double(value);
            disp(app.impulse_dur)
        end

        % Button pushed function: DyeInjectionButton
        function DyeInjectionButtonPushed(app, event)
            app.s = daq.createSession('ni');
            addAnalogOutputChannel(app.s,'Dev1', 2, 'Voltage'); % TTL to GATE
            
            fs=10000;
            t = (0:1/fs:60); % 30 min
            amp = 3; %V
            freq = 30; %There is some scaling of time. Value of 3
            square_wave = square(2*pi*freq*t, 50);
            positive_sqr_wave = (amp/2) + (amp/2)*square_wave;
            queueOutputData(app.s,positive_sqr_wave');
            
            
            [~,~,~] = app.s.startForeground;
            
            stop(app.s);
            
        end

        % Value changed function: SumsinefreqEditField
        function SumsinefreqEditFieldValueChanged(app, event)
            value = app.SumsinefreqEditField.Value;
            app.sum_sine_freqs = str2num(value);
            disp(app.sum_sine_freqs)
        end

        % Value changed function: SumofsinesCheckBox
        function SumofsinesCheckBoxValueChanged(app, event)
            value = app.SumofsinesCheckBox.Value;
            
            
            if value == 1
                %app.sine = 1;
                app.sum_sine_stim = generate_sum_sine();
                %figure();
                %plot(app.sine_stim);
                
                app.output_data = [app.output_data; app.sum_sine_stim];
            end
            
            function sum_sine_all_trials = generate_sum_sine()
                stim_dur_sine = 2*app.off_time+app.on_time;
                t = app.off_time:1/app.sampling_rate:stim_dur_sine-app.off_time;
                
                sum_sine_freq_rand = app.sum_sine_freqs(randperm(length(app.sum_sine_freqs)));
                
                sum_sine_freq_rand_str = repmat(num2str(sum_sine_freq_rand'), app.no_of_trials,1);
                % disp(size(sine_freq_rand_str))
                stim_name = "sum_sin_";
                stim_array = [repmat(stim_name, app.no_of_trials*length(sum_sine_freq_rand), 1) sum_sine_freq_rand_str] ;
                stim_array = join(stim_array);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(app.output_stim_array)
                
                sum_sine_wave = sin(2*pi*sum_sine_freq_rand'*t)+sin(2*pi*3*sum_sine_freq_rand'*t);
                
                for i=1:length(sum_sine_freq_rand)
                    norm_sum_sine_wave(i,:) = sum_sine_wave(i,:)./max(sum_sine_wave(i,:));
                end
                sum_sine_wave_normalised = app.amplitude.*norm_sum_sine_wave;
                off_time_array = zeros(length(app.sum_sine_freqs), app.off_time*app.sampling_rate);
                sum_sine_wave_flanked = [off_time_array sum_sine_wave_normalised off_time_array];
                %plot(sine_wave_flanked);
                
                %                 sum_sine_all_trials = [];
                sum_sine_all_trials = repmat(sum_sine_wave_flanked, app.no_of_trials, 1);
                % %                 sum_sine_all_trials = [sum_sine_all_trials; one_stim_trials];
                
                
            end
            
            
        end

        % Value changed function: Whitenoise2CheckBox
        function Whitenoise2CheckBoxValueChanged(app, event)
            value = app.Whitenoise2CheckBox.Value;
            
            if value == 1
                %app.chirp = 1;
                app.gwn_stim_2 = generate_gwn_stim_2();
                
                %figure();
                %plot(app.gwn_stim);
                
                app.output_data = [app.output_data; app.gwn_stim_2];
            end
            
            
            function gwn_stim2_trials = generate_gwn_stim_2()
                
                gwn_stim_dur = app.on_time + 2*app.off_time;
                flank_off_time = zeros(1, app.off_time*app.sampling_rate);
                t1= app.off_time:1/app.sampling_rate:gwn_stim_dur-app.off_time;
                disp(size(t1));
                
                gwn = wgn(1,length(t1), app.gwn_power, 1, 2); % impedence of load = 1, randseed = 2
                
                d = designfilt('lowpassiir','FilterOrder',10, ...
                    'HalfPowerFrequency',300, ...
                    'SampleRate',app.sampling_rate);
                blgwn = filtfilt(d, gwn);
                
                
                gwn_stim2_trials = [flank_off_time blgwn flank_off_time];
                gwn_stim2_trials = repmat(gwn_stim2_trials, app.no_of_trials, 1);
                
                stim_name = "blwgn2";
                stim_array = repmat(stim_name, app.no_of_trials, 1);
                app.output_stim_array = [app.output_stim_array; stim_array];
                
            end
        end

        % Value changed function: SinewithnoiseCheckBox
        function SinewithnoiseCheckBoxValueChanged(app, event)
            value = app.SinewithnoiseCheckBox.Value;
            
            if value == 1
                %app.sine = 1;
                app.noisy_sine_stim = generate_noisy_sine_wave();
                figure();
                x = linspace(0,15,length(app.noisy_sine_stim));
                plot(x, app.noisy_sine_stim);
                
                app.output_data = [app.output_data; app.noisy_sine_stim];
            end
            
            function noisy_sine_all_trials = generate_noisy_sine_wave()
                stim_dur_sine = 2*app.off_time+app.on_time;
                t = app.off_time:1/app.sampling_rate:stim_dur_sine-app.off_time;
                
                sine_noise_rand = app.sine_noise(randperm(length(app.sine_noise)));
                sine_noise_rand_str = repmat(num2str(sine_noise_rand'), app.no_of_trials,1);
                stim_name = "noisySin_";
                stim_array = [repmat(stim_name, app.no_of_trials*length(sine_noise_rand), 1) sine_noise_rand_str] ;
                stim_array = join(stim_array);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(app.output_stim_array)
                
                d = designfilt('lowpassiir','FilterOrder',10, ...
                    'HalfPowerFrequency',300, ...
                    'SampleRate',app.sampling_rate);
                for i = 1:length(app.sine_noise)
                    gwn = wgn(1,length(t), app.sine_noise(i), 1, 1); % impedence of load = 1, randseed = 1
                    noise_all_powers(i,:) = filtfilt(d, gwn);
                end


                sine_wave = (app.amplitude)*sin(2*pi*30*t); %frequency = 30 Hz
                sine_wave = repmat(sine_wave, length(app.sine_noise),1);
                noisy_sine_wave = noise_all_powers + sine_wave;
                
                off_time_array = zeros(length(app.sine_noise), app.off_time*app.sampling_rate);
                noisy_sine_wave_flanked = [off_time_array noisy_sine_wave off_time_array];
                %plot(sine_wave_flanked);
                
                noisy_sine_all_trials = [];
                for i=1:length(app.sine_noise)
                    one_stim_trials = repmat(noisy_sine_wave_flanked(i,:), app.no_of_trials, 1);
                    noisy_sine_all_trials = [noisy_sine_all_trials; one_stim_trials];
                end
                
            end
        end

        % Value changed function: PowerofsinenoiseEditField
        function PowerofsinenoiseEditFieldValueChanged(app, event)
            value = app.PowerofsinenoiseEditField.Value;
            app.sine_noise = str2num(string(value));
        end

        % Value changed function: StepamplitudesEditField
        function StepamplitudesEditFieldValueChanged(app, event)
            value = app.StepamplitudesEditField.Value;
            app.step_amp = str2num(string(value));
            disp(app.step_amp);
        end

        % Value changed function: VariableampstepsCheckBox
        function VariableampstepsCheckBoxValueChanged(app, event)
            value = app.VariableampstepsCheckBox.Value;
            
            if value == 1
                %                 app.step = 1;
                app.step_var_amp_stim = generate_variable_amp_step_stim();
                %                 figure();
                %                 x= linspace(0,11,length(app.step_var_amp_stim));
                %                 plot(x,app.step_var_amp_stim);
                
                app.output_data = [app.output_data; app.step_var_amp_stim];
                %                 plot(app.step_var_amp_stim);
                
            end
            
            function step_var_amp_all_trials = generate_variable_amp_step_stim()
                
                off_step = app.sampling_rate*app.off_time;
                on_step = app.on_time* app.sampling_rate;
%                 ramp_dur = 1*app.sampling_rate;
                
                step_amp_rand = app.step_amp(randperm(length(app.step_amp)));
                total_dur = 2*off_step + on_step + 2*ramp_dur;
                
                
                step_wave = zeros(length(app.step_amp),total_dur);
                step_var_amp_all_trials = [];
                
                for i=1:length(app.step_amp)
                    ramp_dur = (step_amp_rand(i)/app.amplitude)*app.sampling_rate;
                    step_wave(i, off_step+1:off_step+ramp_dur) = linspace(0,step_amp_rand(i),ramp_dur);
                    step_wave(i, off_step+ramp_dur+1:off_step+ramp_dur+on_step) = step_amp_rand(i);
                    step_wave(i, off_step+ramp_dur+on_step+1:end-off_step) = linspace(step_amp_rand(i),0,ramp_dur);
                    %                       one_stim_trials = repmat(step_wave(i,:), 1, app.no_of_trials);
                    %                       step_all_trials = [step_all_trials one_stim_trials];
                    
                end
                
                one_stim_trials = repmat(step_wave, app.no_of_trials, 1);
                step_var_amp_all_trials = [step_var_amp_all_trials; one_stim_trials];
                
                
                step_var_amp_rand_str = repmat(num2str(step_amp_rand'), app.no_of_trials,1);
                % disp(size(sine_freq_rand_str))
                stim_name = "step_var_amp_";
                stim_array = [repmat(stim_name, app.no_of_trials*length(step_amp_rand), 1) step_var_amp_rand_str] ;
                stim_array = join(stim_array);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(app.output_stim_array)
            end


        end

        % Callback function
        function TimeleftEditFieldValueChanged(app, event)
            value = app.TimeleftEditField.Value;
        end

        % Value changed function: MaxfrequencyEditField
        function MaxfrequencyEditFieldValueChanged(app, event)
            value = app.MaxfrequencyEditField.Value;
            app.max_chirp_freq = value;
        end

        % Value changed function: NostimulusrecordingCheckBox
        function NostimulusrecordingCheckBoxValueChanged(app, event)
            value = app.NostimulusrecordingCheckBox.Value;
            
            
            if value==1
                
                app.s = daq.createSession('ni');
                addAnalogInputChannel(app.s,'Dev2', 0, 'Voltage'); %For recording neuron
%                 addAnalogInputChannel(app.s,'Dev1', 1, 'Voltage'); %Force output from indenter
%                 addAnalogInputChannel(app.s,'Dev1', 2, 'Voltage'); %Hall effect sensor output
%                 addAnalogOutputChannel(app.s,'Dev1', 0, 'Voltage'); %Waveform generator
                
                
                app.s.Rate = app.sampling_rate;
                [rec_data,time,~] = app.s.startForeground;


                cd 'F:\Chinmayee\Recordings'
                
                formatOut = 'yyyy.mm.dd';
                date = datestr(now,formatOut);
                if 7~=exist(date, 'dir')
                    [~, msg] = mkdir(date);
                    disp(msg);
                end
                
                
                dir_path = strcat(pwd,'\', date, '\');
                cd(dir_path);


                %NWB file
                session_start_time = datetime; %date has to be update from the app input
                
                nwb = NwbFile( ...
                    'session_description', app.session_description, ...
                    'identifier', app.session_identifier, ...
                    'session_start_time', session_start_time);
                
                %disp(nwb);
                
                %Subject - animal
                nwb.general_subject = types.core.Subject( ...
                    'description', app.moth_ID, 'age',app.moth_age, ...
                    'sex', app.sex, 'species', 'Daphnis nerii');
                
                nwb.general_stimulus = num2str([app.sampling_rate; app.amplitude; app.dutycycle; app.on_time; app.off_time; app.no_of_trials]);
                %Recording meta-data
                device_name = 'NI PCIe 7852-R';
                ic_elec_name = 'Sharp electrode';
                
                nwb.general_devices.set(device_name, types.core.Device());
                device_link = types.untyped.SoftLink(['/general/devices/' device_name]);
                
                ic_elec = types.core.IntracellularElectrode( ...
                    'device', device_link, ...
                    'description', 'my description');
                
                nwb.general_intracellular_ephys.set(ic_elec_name, ic_elec);
                ic_elec_link = types.untyped.SoftLink(['/general/intracellular_ephys/' ic_elec_name]);
                
                %stimulus
                
                %             description = 'Stimulus to JO';
                stimulus_name = 'mechanical_stimulus';         %change this for air puff


                nwb.stimulus_presentation.set(stimulus_name, ...
                    types.core.CurrentClampStimulusSeries( ...
                    'electrode', ic_elec_link, ...
                    'gain', 10, ...
                    'stimulus_description', app.stim_description, ...
                    'data_unit', 'V', ...
                    'data', app.output_data_rand', ...
                    'timestamps', time));
                
                %Response
                
                response_name = 'response_to_JO_stimulation';
                
                nwb.acquisition.set(response_name, ...
                    types.core.IZeroClampSeries( ...
                    'bias_current', 200, ... % Unit: pA
                    'bridge_balance', app.bridge_balance, ... % Unit: Ohm
                    'capacitance_compensation', [], ... % Unit: Farad
                    'timestamps', time, ... % seconds
                    'data', rec_data, ...
                    'data_unit', 'V', ...
                    'electrode', ic_elec_link, ...
                    'stimulus_description', 'Mechanical stimulus to JO')); %update GUI to collect these data
                
                f = sprintf("%s.nwb", app.filename);
                
                % Check if file exists and update
                if isfile(f)
                    f = sprintf("%s_new.nwb", app.filename);
                    warning('Change the file name');
                end
                
                nwbExport(nwb, f);
                
            end
        end

        % Value changed function: StairsCheckBox
        function StairsCheckBoxValueChanged(app, event)
            value = app.StairsCheckBox.Value;
            
            if value == 1
                no_of_steps = 5;
                delta_amplitude = 2*app.amplitude/no_of_steps;
                
                positions = [0 -0.004 -0.012 -0.02 -0.012 -0.004 0.004 0.012 0.02 0.012 0.004 -0.004 -0.012 -0.02 -0.012 -0.004 0 ];
                
                % t0 = 0
                stair = []; %-app.amplitude;
                %                 stair = zeros(1,on_time*sampling_rate);
                
                %                 init_amplitude = 0;
                %                 for i=1:no_of_steps
                %
                %                     step_amplitude = init_amplitude+delta_amplitude;
                %                     ramp = linspace(init_amplitude, step_amplitude, ramp_duration*app.sampling_rate);
                %                     step = step_amplitude*ones(1,step_duration*app.sampling_rate);
                %                     if step_amplitude==app.amplitude*2
                %                         step = step_amplitude*ones(1,step_duration/2*app.sampling_rate);
                %                     end
                %                     init_amplitude = step_amplitude;
                %
                %                     stair = [stair ramp step];
                %                 end
                %
                %                 flank_step = zeros(1,step_duration*app.sampling_rate);
                %                 stair = [flank_step stair fliplr(stair) flank_step] - app.amplitude;
                %
                %
                %                 plot(stair);
                
                for i=1:length(positions)-1
                    
                    stair_ramp_dur = (positions(i+1) - positions(i))/delta_amplitude;
                    ramp = linspace(positions(i), positions(i+1), stair_ramp_dur*app.sampling_rate);
                    step_amplitude = positions(i+1);
                    
                    if step_amplitude == 0
                        stair = [stair ramp];
                        continue;
                    end
                    step = step_amplitude*ones(1, app.stair_step_dur*app.sampling_rate);
                    
                    stair = [stair ramp step];
                end
                
                %                 disp(size(stair))
                
                flank_off_time = zeros(1, app.off_time*app.sampling_rate);
                stair = [flank_off_time stair flank_off_time];
                app.inc_dec_stair = repmat(stair, app.no_of_trials, 1);
                plot(app.inc_dec_stair(1,:));
                
%                 app.inc_dec_stair = app.inc_dec_stair;
                %                 stim_name = "inc_stair";
                stim_array = repmat("inc_dec_stair", app.no_of_trials, 1);
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(size(app.output_stim_array));
                disp(app.output_stim_array);
                
                app.output_data = [app.output_data; app.inc_dec_stair];
            end
        end

        % Value changed function: RampCheckBox
        function RampCheckBoxValueChanged(app, event)
            value = app.RampCheckBox.Value;
            
            if value == 1
                %                 app.step = 1;
                app.ramp_stim = generate_ramp_stim();
                
                
                app.output_data = [app.output_data; app.ramp_stim];
                
                
            end
            
            function ramp_all_trials = generate_ramp_stim()
                
                off_ramp = app.sampling_rate*app.off_time;
                ramp_dur_rand = app.ramp_dur(randperm(length(app.ramp_dur)));
                disp(ramp_dur_rand)
                ramp_dur = ramp_dur_rand * app.sampling_rate;
                step_dur = app.ramp_step_dur*app.sampling_rate;
                total_dur = 2*off_ramp + 2*ramp_dur + step_dur;
                
                
                %                 ramp_wave = zeros(2*length(app.ramp_dur),max(total_dur));
                %                 inc_ramp = zeros(length(app.ramp_dur),max(total_dur));
                %                 dec_ramp = zeros(length(app.ramp_dur),max(total_dur));
                var_ramp = zeros(length(app.ramp_dur),max(total_dur));
                
                ramp_all_trials = [];
                
                for i=1:length(app.ramp_dur)
                    
                    var_ramp(i, off_ramp+1:off_ramp+ramp_dur(i)) = linspace(0,app.amplitude,ramp_dur(i));
                    var_ramp(i, off_ramp+ramp_dur(i)+1:off_ramp+ramp_dur(i)+step_dur) = app.amplitude;
                    var_ramp(i, off_ramp+ramp_dur(i)+step_dur+1:off_ramp+ramp_dur(i)+step_dur+ramp_dur(i)) = linspace(app.amplitude,0,ramp_dur(i));
                    
                end
                
                one_stim_trials = repmat(var_ramp, app.no_of_trials, 1);
                ramp_all_trials = one_stim_trials;
                
                ramp_dur_rand_str = repmat(num2str(ramp_dur_rand'), app.no_of_trials,1);
                % disp(size(sine_freq_rand_str))
                %                 stim_name = "ramp_";
                stim_array = [repmat("var_ramp", app.no_of_trials*length(ramp_dur_rand), 1) ramp_dur_rand_str] ;
                stim_array = join(stim_array);
                
                app.output_stim_array = [app.output_stim_array; stim_array];
                disp(app.output_stim_array)
            end
        end

        % Value changed function: RampdurationsEditField
        function RampdurationsEditFieldValueChanged(app, event)
            value = app.RampdurationsEditField.Value;
            app.ramp_dur = str2num(string(value));
        end

        % Button pushed function: OnlystimulusButton
        function OnlystimulusButtonPushed(app, event)
            app.s = daq.createSession('ni');
            addAnalogInputChannel(app.s,'Dev2', 0, 'Voltage'); %For recording neuron
            addAnalogInputChannel(app.s,'Dev2', 1, 'Voltage'); %Force output from indenter
            addAnalogInputChannel(app.s,'Dev2', 2, 'Voltage'); %Hall effect sensor output
            addAnalogOutputChannel(app.s,'Dev2', 0, 'Voltage'); %Waveform generator
            
            
            app.s.Rate = app.sampling_rate;


            %randomise the stimuli and trials
            idx = randperm(size(app.output_data,1));
            app.output_data_rand = app.output_data(idx, :);
            app.output_data_rand_row = reshape(app.output_data_rand', [], 1);
            
            app.output_stim_array_rand = app.output_stim_array(idx,:);
            app.stim_description_string_array = (app.output_stim_array_rand)';
            
            app.stim_description = char(join(app.stim_description_string_array, ","));
            
            %             figure();
            plot(app.output_data_rand_row);
            
            queueOutputData(app.s,app.output_data_rand_row);
            [rec_data,time,~] = app.s.startForeground;
            
            stop(app.s);
            beep;
        end

        % Value changed function: StepdurationEditField
        function StepdurationEditFieldValueChanged(app, event)
            value = app.StepdurationEditField.Value;
            app.stair_step_dur = value;
        end

        % Value changed function: RampdurationEditField
        function RampdurationEditFieldValueChanged(app, event)
            value = app.RampdurationEditField.Value;
            app.stair_ramp_dur = value;
        end

        % Value changed function: RampstepdurationEditField
        function RampstepdurationEditFieldValueChanged(app, event)
            value = app.RampstepdurationEditField.Value;
            app.ramp_step_dur = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.DutycycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutycycleEditFieldLabel.Text = 'Duty cycle';
            app.DutycycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutycycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutycycleEditFieldValueChanged, true);
            app.ONtimeEditFieldLabel = uilabel(app.UIFigure);
            app.ONtimeEditFieldLabel.Text = 'ON time';
            app.ONtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ONtimeEditField.ValueChangedFcn = createCallbackFcn(app, @ONtimeEditFieldValueChanged, true);
            app.OFFtimeEditFieldLabel = uilabel(app.UIFigure);
            app.OFFtimeEditFieldLabel.Text = 'OFF time';
            app.OFFtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OFFtimeEditField.ValueChangedFcn = createCallbackFcn(app, @OFFtimeEditFieldValueChanged, true);
            app.NooftrialsEditFieldLabel = uilabel(app.UIFigure);
            app.NooftrialsEditFieldLabel.Text = 'No. of trials';
            app.NooftrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NooftrialsEditField.ValueChangedFcn = createCallbackFcn(app, @NooftrialsEditFieldValueChanged, true);
            app.FrequencychirpCheckBox = uicheckbox(app.UIFigure);
            app.FrequencychirpCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrequencychirpCheckBoxValueChanged, true);
            app.FrequencychirpCheckBox.Text = 'Frequency chirp';
            app.SquarewaveCheckBox = uicheckbox(app.UIFigure);
            app.SquarewaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SquarewaveCheckBoxValueChanged, true);
            app.SquarewaveCheckBox.Text = 'Square wave';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.SessiondescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.SessiondescriptionEditFieldLabel.Text = 'Session description';
            app.SessiondescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.SessiondescriptionEditField.ValueChangedFcn = createCallbackFcn(app, @SessiondescriptionEditFieldValueChanged, true);
            app.SessionidentifierEditFieldLabel = uilabel(app.UIFigure);
            app.SessionidentifierEditFieldLabel.Text = 'Session identifier';
            app.SessionidentifierEditField = uieditfield(app.UIFigure, 'text');
            app.SessionidentifierEditField.ValueChangedFcn = createCallbackFcn(app, @SessionidentifierEditFieldValueChanged, true);
            app.MothIDEditFieldLabel = uilabel(app.UIFigure);
            app.MothIDEditFieldLabel.Text = 'Moth ID';
            app.MothIDEditField = uieditfield(app.UIFigure, 'text');
            app.MothIDEditField.ValueChangedFcn = createCallbackFcn(app, @MothIDEditFieldValueChanged, true);
            app.MothageEditFieldLabel = uilabel(app.UIFigure);
            app.MothageEditFieldLabel.Text = 'Moth age';
            app.MothageEditField = uieditfield(app.UIFigure, 'text');
            app.MothageEditField.ValueChangedFcn = createCallbackFcn(app, @MothageEditFieldValueChanged, true);
            app.SexEditFieldLabel = uilabel(app.UIFigure);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.UIFigure, 'text');
            app.SexEditField.ValueChangedFcn = createCallbackFcn(app, @SexEditFieldValueChanged, true);
            app.BridgebalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BridgebalanceEditFieldLabel.Text = 'Bridge balance';
            app.BridgebalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.BridgebalanceEditField.ValueChangedFcn = createCallbackFcn(app, @BridgebalanceEditFieldValueChanged, true);
            app.SineCheckBox = uicheckbox(app.UIFigure);
            app.SineCheckBox.ValueChangedFcn = createCallbackFcn(app, @SineCheckBoxValueChanged, true);
            app.SineCheckBox.Text = 'Sine';
            app.AmplitudesweepCheckBox = uicheckbox(app.UIFigure);
            app.AmplitudesweepCheckBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudesweepCheckBoxValueChanged, true);
            app.AmplitudesweepCheckBox.Text = 'Amplitude sweep';
            app.WhitenoiseCheckBox = uicheckbox(app.UIFigure);
            app.WhitenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhitenoiseCheckBoxValueChanged, true);
            app.WhitenoiseCheckBox.Text = 'White noise';
            app.Amp_minEditFieldLabel = uilabel(app.UIFigure);
            app.Amp_minEditFieldLabel.Text = 'Amp_min';
            app.Amp_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Amp_minEditField.ValueChangedFcn = createCallbackFcn(app, @Amp_minEditFieldValueChanged, true);
            app.Amp_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Amp_maxEditFieldLabel.Text = 'Amp_max';
            app.Amp_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Amp_maxEditField.ValueChangedFcn = createCallbackFcn(app, @Amp_maxEditFieldValueChanged, true);
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditField.ValueChangedFcn = createCallbackFcn(app, @PowerEditFieldValueChanged, true);
            app.BridgebalanceButton = uibutton(app.UIFigure, 'push');
            app.BridgebalanceButton.ButtonPushedFcn = createCallbackFcn(app, @BridgebalanceButtonPushed, true);
            app.BridgebalanceButton.Text = 'Bridge balance';
            app.SqrwavefreqEditFieldLabel = uilabel(app.UIFigure);
            app.SqrwavefreqEditFieldLabel.Text = 'Sqr wave freq';
            app.SqrwavefreqEditField = uieditfield(app.UIFigure, 'text');
            app.SqrwavefreqEditField.ValueChangedFcn = createCallbackFcn(app, @SqrwavefreqEditFieldValueChanged, true);
            app.SinewavefreqEditFieldLabel = uilabel(app.UIFigure);
            app.SinewavefreqEditFieldLabel.Text = 'Sine wave freq';
            app.SinewavefreqEditField = uieditfield(app.UIFigure, 'text');
            app.SinewavefreqEditField.ValueChangedFcn = createCallbackFcn(app, @SinewavefreqEditFieldValueChanged, true);
            app.Sweep_freqEditFieldLabel = uilabel(app.UIFigure);
            app.Sweep_freqEditFieldLabel.Text = 'Sweep_freq';
            app.Sweep_freqEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sweep_freqEditField.ValueChangedFcn = createCallbackFcn(app, @Sweep_freqEditFieldValueChanged, true);
            app.DecreasingFrequencychirpCheckBox = uicheckbox(app.UIFigure);
            app.DecreasingFrequencychirpCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecreasingFrequencychirpCheckBoxValueChanged, true);
            app.DecreasingFrequencychirpCheckBox.Text = ' Decreasing Frequency chirp';
            app.StepCheckBox = uicheckbox(app.UIFigure);
            app.StepCheckBox.ValueChangedFcn = createCallbackFcn(app, @StepCheckBoxValueChanged, true);
            app.StepCheckBox.Text = 'Step';
            app.StepdurationsEditFieldLabel = uilabel(app.UIFigure);
            app.StepdurationsEditFieldLabel.Text = 'Step durations';
            app.StepdurationsEditField = uieditfield(app.UIFigure, 'text');
            app.StepdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @StepdurationsEditFieldValueChanged, true);
            app.ImpulseCheckBox = uicheckbox(app.UIFigure);
            app.ImpulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImpulseCheckBoxValueChanged, true);
            app.ImpulseCheckBox.Text = 'Impulse';
            app.ImpulsedurationEditFieldLabel = uilabel(app.UIFigure);
            app.ImpulsedurationEditFieldLabel.Text = 'Impulse duration';
            app.ImpulsedurationEditField = uieditfield(app.UIFigure, 'text');
            app.ImpulsedurationEditField.ValueChangedFcn = createCallbackFcn(app, @ImpulsedurationEditFieldValueChanged, true);
            app.DyeInjectionButton = uibutton(app.UIFigure, 'push');
            app.DyeInjectionButton.ButtonPushedFcn = createCallbackFcn(app, @DyeInjectionButtonPushed, true);
            app.DyeInjectionButton.Text = 'Dye Injection';
            app.SumofsinesCheckBox = uicheckbox(app.UIFigure);
            app.SumofsinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SumofsinesCheckBoxValueChanged, true);
            app.SumofsinesCheckBox.Text = 'Sum of sines';
            app.SumsinefreqEditFieldLabel = uilabel(app.UIFigure);
            app.SumsinefreqEditFieldLabel.Text = 'Sum sine freq';
            app.SumsinefreqEditField = uieditfield(app.UIFigure, 'text');
            app.SumsinefreqEditField.ValueChangedFcn = createCallbackFcn(app, @SumsinefreqEditFieldValueChanged, true);
            app.Whitenoise2CheckBox = uicheckbox(app.UIFigure);
            app.Whitenoise2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Whitenoise2CheckBoxValueChanged, true);
            app.Whitenoise2CheckBox.Text = 'White noise 2';
            app.SinewithnoiseCheckBox = uicheckbox(app.UIFigure);
            app.SinewithnoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SinewithnoiseCheckBoxValueChanged, true);
            app.SinewithnoiseCheckBox.Text = 'Sine with noise';
            app.PowerofsinenoiseEditFieldLabel = uilabel(app.UIFigure);
            app.PowerofsinenoiseEditFieldLabel.Text = 'Power of sine noise';
            app.PowerofsinenoiseEditField = uieditfield(app.UIFigure, 'text');
            app.PowerofsinenoiseEditField.ValueChangedFcn = createCallbackFcn(app, @PowerofsinenoiseEditFieldValueChanged, true);
            app.VariableampstepsCheckBox = uicheckbox(app.UIFigure);
            app.VariableampstepsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VariableampstepsCheckBoxValueChanged, true);
            app.VariableampstepsCheckBox.Text = 'Variable amp steps';
            app.StepamplitudesEditFieldLabel = uilabel(app.UIFigure);
            app.StepamplitudesEditFieldLabel.Text = 'Step amplitudes';
            app.StepamplitudesEditField = uieditfield(app.UIFigure, 'text');
            app.StepamplitudesEditField.ValueChangedFcn = createCallbackFcn(app, @StepamplitudesEditFieldValueChanged, true);
            app.MaxfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.MaxfrequencyEditFieldLabel.Text = 'Max frequency';
            app.MaxfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MaxfrequencyEditFieldValueChanged, true);
            app.NostimulusrecordingCheckBox = uicheckbox(app.UIFigure);
            app.NostimulusrecordingCheckBox.ValueChangedFcn = createCallbackFcn(app, @NostimulusrecordingCheckBoxValueChanged, true);
            app.NostimulusrecordingCheckBox.Text = 'No stimulus recording';
            app.StairsCheckBox = uicheckbox(app.UIFigure);
            app.StairsCheckBox.ValueChangedFcn = createCallbackFcn(app, @StairsCheckBoxValueChanged, true);
            app.StairsCheckBox.Text = 'Stairs';
            app.RampCheckBox = uicheckbox(app.UIFigure);
            app.RampCheckBox.ValueChangedFcn = createCallbackFcn(app, @RampCheckBoxValueChanged, true);
            app.RampCheckBox.Text = 'Ramp';
            app.RampdurationsEditFieldLabel = uilabel(app.UIFigure);
            app.RampdurationsEditFieldLabel.Text = 'Ramp durations';
            app.RampdurationsEditField = uieditfield(app.UIFigure, 'text');
            app.RampdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @RampdurationsEditFieldValueChanged, true);
            app.OnlystimulusButton = uibutton(app.UIFigure, 'push');
            app.OnlystimulusButton.ButtonPushedFcn = createCallbackFcn(app, @OnlystimulusButtonPushed, true);
            app.OnlystimulusButton.Text = 'Only stimulus';
            app.StepdurationEditFieldLabel = uilabel(app.UIFigure);
            app.StepdurationEditFieldLabel.Text = 'Step duration';
            app.StepdurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepdurationEditField.ValueChangedFcn = createCallbackFcn(app, @StepdurationEditFieldValueChanged, true);
            app.RampdurationEditFieldLabel = uilabel(app.UIFigure);
            app.RampdurationEditFieldLabel.Text = 'Ramp duration';
            app.RampdurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RampdurationEditField.ValueChangedFcn = createCallbackFcn(app, @RampdurationEditFieldValueChanged, true);
            app.RampstepdurationEditFieldLabel = uilabel(app.UIFigure);
            app.RampstepdurationEditFieldLabel.Text = 'Ramp step duration';
            app.RampstepdurationEditField = uieditfield(app.UIFigure, 'text');
            app.RampstepdurationEditField.ValueChangedFcn = createCallbackFcn(app, @RampstepdurationEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stim_rec_app_nwb_v7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end